classdef Smart_Eco_Gym < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FanDesignMenu      matlab.ui.container.Menu
        Part1Menu          matlab.ui.container.Menu
        Part2Menu          matlab.ui.container.Menu
        ElectricityMenu    matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        Fans1Panel         matlab.ui.container.Panel
        CalculateButton    matlab.ui.control.Button
        F04_L_Eff          matlab.ui.control.Label
        F02_L_Eff          matlab.ui.control.Label
        F01_L_Eff          matlab.ui.control.Label
        F03_L_Eff          matlab.ui.control.Label
        F04_L_ACH          matlab.ui.control.Label
        F02_L_ACH          matlab.ui.control.Label
        F01_L_ACH          matlab.ui.control.Label
        F03_L_ACH          matlab.ui.control.Label
        F01_E              matlab.ui.control.UIAxes
        F02_E              matlab.ui.control.UIAxes
        F04_E              matlab.ui.control.UIAxes
        F03_E              matlab.ui.control.UIAxes
        Fans2Panel         matlab.ui.container.Panel
        CalculateButton_2  matlab.ui.control.Button
        F08_L_Eff          matlab.ui.control.Label
        F07_L_Eff          matlab.ui.control.Label
        F06_L_Eff          matlab.ui.control.Label
        F05_L_Eff          matlab.ui.control.Label
        F07_L_ACH          matlab.ui.control.Label
        F08_L_ACH          matlab.ui.control.Label
        F06_L_ACH          matlab.ui.control.Label
        F05_L_ACH          matlab.ui.control.Label
        F08_E              matlab.ui.control.UIAxes
        F06_E              matlab.ui.control.UIAxes
        F07_E              matlab.ui.control.UIAxes
        F05_E              matlab.ui.control.UIAxes
        Electricity1panel  matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        CalculateButton_3  matlab.ui.control.Button
        NetwattageLabel    matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        Watt_intake        matlab.ui.control.UIAxes
        Wattage_usage      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         % Description
    end
    %Fan functions
    methods (Access = public)
        function F0X_ACH= find_ACH(app, test_1,test_2,test_3)
            load("fan_air_velocity.mat","air_velocity");
            fan_id=air_velocity.test_id;
            unique_ids=unique(air_velocity.test_id);
            speed=air_velocity.air_speed_mps;
            T001=find(fan_id==string(unique_ids(test_1)));
            T002=find(fan_id==string(unique_ids(test_2)));
            T003=find(fan_id==string(unique_ids(test_3)));
            avg_speeds=speed(T001)+speed(T002)+speed(T003)/3;

            speeds=mean(avg_speeds(avg_speeds>0));
            ft_min=speeds*196.8504;%takes average speed given by the three tests
            CFM=ft_min*(19.4);%assumption made by common door size
            F0X_ACH =(CFM*60)/(32.8084*26.2467*9.84252);
        end

        %doesn't account for pressure so it is an estimation
        function avg_wattage= avg_power_usage(app,test_1,test_2,test_3)
            load("fan_power_consumption.mat","power_consumption");
            watts=power_consumption.power_W;
            unique_id_watt=unique(power_consumption.test_id);
            fan_id_watt=(power_consumption.test_id);
            %finding indexes of each
            T001_watt=find(fan_id_watt==string(unique_id_watt(test_1)));
            T002_watt=find(fan_id_watt==string(unique_id_watt(test_2)));
            T003_watt=find(fan_id_watt==string(unique_id_watt(test_3)));
            %Average
            avg_wattage= (watts(T001_watt)+watts(T002_watt)+watts(T003_watt))/3;
        end

        function watt_time= watt_time(app,test_1,test_2,test_3)
            load("fan_power_consumption.mat","power_consumption");
            unique_id=unique(power_consumption.test_id);
            time= power_consumption.time_sec;
            fan_id_time=power_consumption.test_id;
            %index
            T001_time=find(fan_id_time==string(unique_id(test_1)));
            T002_time=find(fan_id_time==string(unique_id(test_2)));
            T003_time=find(fan_id_time==string(unique_id(test_3)));
            watt_time=time(T001_time(1):T001_time(end));
        end


        function power_eff=find_power(app,test_1,test_2,test_3)
            load("fan_power_consumption.mat","power_consumption");
            watts=power_consumption.power_W;
            unique_id_watt=unique(power_consumption.test_id);
            fan_id_watt=(power_consumption.test_id);

            T001_watt=find(fan_id_watt==string(unique_id_watt(test_1)));
            T002_watt=find(fan_id_watt==string(unique_id_watt(test_2)));
            T003_watt=find(fan_id_watt==string(unique_id_watt(test_3)));

            avg_wattage= (watts(T001_watt)+watts(T002_watt)+watts(T003_watt))/3;

            single_watt=mean(avg_wattage(avg_wattage>0));

            power_eff=(find_ACH(app,test_1,test_2,test_3)/single_watt)*100;
        end
%electric generation functions
        function watt_in= wattage_input(app)
            
            load("renewable_generation.mat","renewable_generation");
            watts=renewable_generation.generated_power_W;
            id=renewable_generation.equipment_id;
            unique_ids=unique(renewable_generation.equipment_id);
            total_watt=0;
            %indexing
            for i=1:length(unique_ids)
                idx=ismember(id,unique_ids(i));
                total_watt=total_watt+watts(idx);
            end
            watt_in=total_watt;

        end
        
        function watt_out=wattage_usage(app)
            load("facility_energy_meters.mat","facility_energy");
            id=facility_energy.system_id;
            watts=facility_energy.power_W;
            unique_ids=unique(facility_energy.system_id);
            total_watt=0;
            for i=1:length(unique_ids)
                idx=ismember(id,unique_ids(i));
                total_watt=total_watt+watts(idx);
            end
            watt_out=total_watt;
        end

        function fan_watt=fan_watt(app)
            %load("facility_energy","facility_energy");
            watt=facility_energy.power_W;
            circ=facility_energy.system_category;
            fan_watt=watt(circ=="air_circulation");
            
        end

        
%plotting fans
        function full_plot(app, WattAxes, ACHLabel, EffLabel, test_1, test_2, test_3)

            %Plot wattage on provided axes
            F0X_watt = avg_power_usage(app, test_1, test_2, test_3);
            watt_time_X = watt_time(app,test_1,test_2, test_3);
            smooth_F0X= smoothdata(F0X_watt);
            plot(WattAxes, watt_time_X, smooth_F0X);
    
            % Set ACH and Efficiency labels (convert numeric to string)
            ACH_F0X = find_ACH(app, test_1, test_2, test_3);
            ACHLabel.Text = "ACH: " + string(ACH_F0X);
            %EFF
            
            Eff_F0X = find_power(app, test_1, test_2, test_3);
            EffLabel.Text = "Efficiency: " + string(Eff_F0X);
        end

        function Elec_plotting (app)
            %intake
            load("renewable_generation.mat","renewable_generation");
            wattage_in= renewable_generation.generated_power_W;
            time_in= renewable_generation.time_sec;
            watt_in=smoothdata(wattage_in);
            plot(app.Watt_intake,time_in,watt_in);
            disp("intake displayed")
            %usage
            load("facility_energy_meters.mat","facility_energy");
            wattage_out= wattage_usage(app);
            smooth_watt=smoothdata(wattage_out);
            time_out=unique(facility_energy.time_sec);
            plot(app.Wattage_usage,time_out,smooth_watt);
            disp("usage displayed")
            %net wattage
            net_wattage=(mean(wattage_out(wattage_out>0))-mean(wattage_in(wattage_in>0)))/1000;
            app.NetwattageLabel.Text="Net wattage: "+net_wattage+" Kilowatt";
            disp(net_wattage)
            disp("net wattage displayed")
        end

        function  pie_graph(app)
            %proportions
            load("facility_energy_meters.mat","facility_energy");
            systems=facility_energy.system_id;
            unique_system=unique(facility_energy.system_id);
            watts=facility_energy.power_W;
            data=zeros(length(unique_system),1);
            %indexing
            for i=1:length(unique_system)
                Sys_X= find(systems==string(unique_system(i)));
                sum_wattage_X= sum(watts(Sys_X));
                data(i)=sum_wattage_X/100;
            end
            labels = {'Air Circulation','Cardio','HVAC','lightting','General plug loads'};
            pie(app.UIAxes,data);
            
            legend(app.UIAxes, labels, 'Location','eastoutside')
            title(app.UIAxes, 'percentage of usage')
        end

    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app)

        end

        % Callback function: CalculateButton, F01_E, F02_E, F03_E, F04_E
        function draw_page_1(app, event)
            
            full_plot(app, app.F01_E, app.F01_L_ACH, app.F01_L_Eff, 1, 2, 3);
            full_plot(app, app.F02_E, app.F02_L_ACH, app.F02_L_Eff, 4, 5, 6);
            full_plot(app, app.F03_E, app.F03_L_ACH, app.F03_L_Eff, 7, 8, 9);
            full_plot(app, app.F04_E, app.F04_L_ACH, app.F04_L_Eff, 10, 11, 12);
            
        end

        % Menu selected function: Part1Menu
        function make_Fan1_Vis(app, event)
            app.Electricity1panel.Visible="off";
            app.Fans1Panel.Visible="on";
            app.Fans2Panel.Visible="off";         
        end

        % Menu selected function: ElectricityMenu
        function make_Elec_Vis(app, event)
            app.Electricity1panel.Visible="on";
            app.Fans1Panel.Visible="off";
            app.Fans2Panel.Visible="off";
        end

        % Menu selected function: Part2Menu
        function make_Fan2_Vis(app, event)
            app.Electricity1panel.Visible="off";
            app.Fans1Panel.Visible="off";
            app.Fans2Panel.Visible="on";
        end

        % Callback function: CalculateButton_3, Watt_intake, Wattage_usage
        function draw_Elec(app, event)
              Elec_plotting(app);
              
              pie_graph(app);
        end

        % Callback function: CalculateButton_2, F05_E, F06_E, F07_E
        function draw_page_2(app, event)
            full_plot(app, app.F05_E, app.F05_L_ACH, app.F05_L_Eff, 1, 2, 3);
            full_plot(app, app.F06_E, app.F06_L_ACH, app.F06_L_Eff, 4, 5, 6);
            full_plot(app, app.F07_E, app.F07_L_ACH, app.F07_L_Eff, 7, 8, 9);
            full_plot(app, app.F08_E, app.F08_L_ACH, app.F08_L_Eff, 10, 11, 12);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FanDesignMenu = uimenu(app.UIFigure);
            app.FanDesignMenu.Text = 'Fan Design';
            app.Part1Menu = uimenu(app.FanDesignMenu);
            app.Part1Menu.MenuSelectedFcn = createCallbackFcn(app, @make_Fan1_Vis, true);
            app.Part1Menu.Text = 'Part 1';
            app.Part2Menu = uimenu(app.FanDesignMenu);
            app.Part2Menu.MenuSelectedFcn = createCallbackFcn(app, @make_Fan2_Vis, true);
            app.Part2Menu.Text = 'Part 2';
            app.ElectricityMenu = uimenu(app.UIFigure);
            app.ElectricityMenu.MenuSelectedFcn = createCallbackFcn(app, @make_Elec_Vis, true);
            app.ElectricityMenu.Text = 'Electricity';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Electricity1panel = uipanel(app.GridLayout);
            app.Electricity1panel.Title = 'Electricity';
            app.GridLayout2 = uigridlayout(app.Electricity1panel);
            app.Wattage_usage = uiaxes(app.GridLayout2);
            title(app.Wattage_usage, 'usage')
            xlabel(app.Wattage_usage, 'X')
            ylabel(app.Wattage_usage, 'Y')
            zlabel(app.Wattage_usage, 'Z')
            app.Wattage_usage.ButtonDownFcn = createCallbackFcn(app, @draw_Elec, true);
            app.Watt_intake = uiaxes(app.GridLayout2);
            title(app.Watt_intake, 'total day intake')
            xlabel(app.Watt_intake, 'Time(seconds)')
            ylabel(app.Watt_intake, 'Watts')
            zlabel(app.Watt_intake, 'Z')
            app.Watt_intake.ButtonDownFcn = createCallbackFcn(app, @draw_Elec, true);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NetwattageLabel = uilabel(app.GridLayout2);
            app.NetwattageLabel.Text = 'Net wattage:';
            app.CalculateButton_3 = uibutton(app.GridLayout2, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @draw_Elec, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.Fans2Panel = uipanel(app.GridLayout);
            app.Fans2Panel.Title = 'Fans 2';
            app.F05_E = uiaxes(app.Fans2Panel);
            title(app.F05_E, 'Curved Short Low Pitch')
            xlabel(app.F05_E, 'Time (Seconds)')
            ylabel(app.F05_E, 'Watt')
            zlabel(app.F05_E, 'Z')
            app.F05_E.ButtonDownFcn = createCallbackFcn(app, @draw_page_2, true);
            app.F07_E = uiaxes(app.Fans2Panel);
            title(app.F07_E, 'Curved Long Low Pitch')
            xlabel(app.F07_E, 'Time (Seconds)')
            ylabel(app.F07_E, 'Watt')
            zlabel(app.F07_E, 'Z')
            app.F07_E.ButtonDownFcn = createCallbackFcn(app, @draw_page_2, true);
            app.F06_E = uiaxes(app.Fans2Panel);
            title(app.F06_E, 'Curved Short High Pitch')
            xlabel(app.F06_E, 'Time (Seconds)')
            ylabel(app.F06_E, 'Watt')
            zlabel(app.F06_E, 'Z')
            app.F06_E.ButtonDownFcn = createCallbackFcn(app, @draw_page_2, true);
            app.F08_E = uiaxes(app.Fans2Panel);
            title(app.F08_E, 'Curved Long High Pitch')
            xlabel(app.F08_E, 'Time (Seconds)')
            ylabel(app.F08_E, 'Watt')
            zlabel(app.F08_E, 'Z')
            app.F05_L_ACH = uilabel(app.Fans2Panel);
            app.F05_L_ACH.Text = 'ACH:';
            app.F06_L_ACH = uilabel(app.Fans2Panel);
            app.F06_L_ACH.Text = 'ACH:';
            app.F08_L_ACH = uilabel(app.Fans2Panel);
            app.F08_L_ACH.Text = 'ACH:';
            app.F07_L_ACH = uilabel(app.Fans2Panel);
            app.F07_L_ACH.Text = 'ACH';
            app.F05_L_Eff = uilabel(app.Fans2Panel);
            app.F05_L_Eff.Text = 'Efficiency:';
            app.F06_L_Eff = uilabel(app.Fans2Panel);
            app.F06_L_Eff.Text = 'Efficiency:';
            app.F07_L_Eff = uilabel(app.Fans2Panel);
            app.F07_L_Eff.Text = 'Efficiency:';
            app.F08_L_Eff = uilabel(app.Fans2Panel);
            app.F08_L_Eff.Text = 'Efficiency:';
            app.CalculateButton_2 = uibutton(app.Fans2Panel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @draw_page_2, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.Fans1Panel = uipanel(app.GridLayout);
            app.Fans1Panel.Title = 'Fans 1';
            app.F03_E = uiaxes(app.Fans1Panel);
            title(app.F03_E, 'Flat Long Low Pitch')
            xlabel(app.F03_E, 'Time (Seconds')
            ylabel(app.F03_E, 'Watts')
            zlabel(app.F03_E, 'Z')
            app.F03_E.ButtonDownFcn = createCallbackFcn(app, @draw_page_1, true);
            app.F04_E = uiaxes(app.Fans1Panel);
            title(app.F04_E, 'Flat Long High Pitch')
            xlabel(app.F04_E, 'Time (Seconds)')
            ylabel(app.F04_E, 'Watts')
            zlabel(app.F04_E, 'Z')
            app.F04_E.ButtonDownFcn = createCallbackFcn(app, @draw_page_1, true);
            app.F02_E = uiaxes(app.Fans1Panel);
            title(app.F02_E, 'Flat Short High Pitch')
            xlabel(app.F02_E, 'Time (Seconds)')
            ylabel(app.F02_E, 'Watts')
            zlabel(app.F02_E, 'Z')
            app.F02_E.ButtonDownFcn = createCallbackFcn(app, @draw_page_1, true);
            app.F01_E = uiaxes(app.Fans1Panel);
            title(app.F01_E, 'Flat Short Low Pitch')
            xlabel(app.F01_E, 'Time (Seconds)')
            ylabel(app.F01_E, 'Watt')
            zlabel(app.F01_E, 'Z')
            app.F01_E.ButtonDownFcn = createCallbackFcn(app, @draw_page_1, true);
            app.F03_L_ACH = uilabel(app.Fans1Panel);
            app.F03_L_ACH.Text = 'ACH: ';
            app.F01_L_ACH = uilabel(app.Fans1Panel);
            app.F01_L_ACH.Text = 'ACH:';
            app.F02_L_ACH = uilabel(app.Fans1Panel);
            app.F02_L_ACH.Text = 'ACH: ';
            app.F04_L_ACH = uilabel(app.Fans1Panel);
            app.F04_L_ACH.Text = 'ACH: ';
            app.F03_L_Eff = uilabel(app.Fans1Panel);
            app.F03_L_Eff.Text = 'Efficiency: ';
            app.F01_L_Eff = uilabel(app.Fans1Panel);
            app.F01_L_Eff.Text = 'Efficiency:';
            app.F02_L_Eff = uilabel(app.Fans1Panel);
            app.F02_L_Eff.Text = 'Efficiency: ';
            app.F04_L_Eff = uilabel(app.Fans1Panel);
            app.F04_L_Eff.Text = 'Efficiency: ';
            app.CalculateButton = uibutton(app.Fans1Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @draw_page_1, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Smart_Eco_Gym

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end